classdef VNA_control_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RightPanel                     matlab.ui.container.Panel
        ConnectionStatusLamp           matlab.ui.control.Lamp
        ConnectionStatusLampLabel      matlab.ui.control.Label
        RemainingEditField             matlab.ui.control.EditField
        RemainingEditFieldLabel        matlab.ui.control.Label
        ElapsedEditField               matlab.ui.control.EditField
        ElapsedEditFieldLabel          matlab.ui.control.Label
        CollectedFilesListBox          matlab.ui.control.ListBox
        CollectedFilesListBoxLabel     matlab.ui.control.Label
        CurrentFilenameEditField       matlab.ui.control.EditField
        CurrentFilenameEditFieldLabel  matlab.ui.control.Label
        SavingDataLamp                 matlab.ui.control.Lamp
        SavingDataLampLabel            matlab.ui.control.Label
        SweepingLamp                   matlab.ui.control.Lamp
        SweepingLampLabel              matlab.ui.control.Label
        progress_FG                    matlab.ui.control.Label
        progress_BG                    matlab.ui.control.Label
        percentLabel                   matlab.ui.control.Label
        ProgressLabel                  matlab.ui.control.Label
        StateEditField                 matlab.ui.control.EditField
        StateEditFieldLabel            matlab.ui.control.Label
        sParamAxes                     matlab.ui.control.UIAxes
        dataCollectAxes                matlab.ui.control.UIAxes
        LeftPanel                      matlab.ui.container.Panel
        RuntimehrEditField             matlab.ui.control.NumericEditField
        RuntimehrEditFieldLabel        matlab.ui.control.Label
        ReaddataButton                 matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        MessageTextArea                matlab.ui.control.TextArea
        MessageTextAreaLabel           matlab.ui.control.Label
        PointsEditField                matlab.ui.control.NumericEditField
        PointsEditFieldLabel           matlab.ui.control.Label
        RBWHzEditField                 matlab.ui.control.NumericEditField
        RBWHzEditFieldLabel            matlab.ui.control.Label
        SweepStopMHzEditField          matlab.ui.control.NumericEditField
        SweepStopMHzEditFieldLabel     matlab.ui.control.Label
        SweepStartMHzEditField         matlab.ui.control.NumericEditField
        SweepStartMHzEditFieldLabel    matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % R&S ZNB20
        visausb % Description
        sParamLine   % line handle
        appTimer   % timer object (countdown)
        timeRemaining % seconds
        timeElapsed
        progress % percentage


    end
    
    
    methods (Access = private)
        
        function countdownCallback(app)
            disp('tick');
            if app.timeRemaining > 0
                app.timeRemaining = app.timeRemaining - 1;

                % elapsed
                app.timeElapsed = app.timeElapsed + 1;

                % format remaining
                hours   = floor( app.timeRemaining / 3600);
                minutes = floor(mod( app.timeRemaining, 3600) / 60);
                seconds = mod( app.timeRemaining, 60);
                app.RemainingEditField.Value = sprintf('%02d:%02d:%02d', hours, minutes, seconds);

                % format elapsed
                hours   = floor( app.timeElapsed / 3600);
                minutes = floor(mod( app.timeElapsed, 3600) / 60);
                seconds = mod( app.timeElapsed, 60);
                app.ElapsedEditField.Value = sprintf('%02d:%02d:%02d', hours, minutes, seconds);

                % update progress bar + label
                app.progress = round(app.timeElapsed / (3600*app.RuntimehrEditField.Value),2);
                app.percentLabel.Text = strcat(num2str(1e2*app.progress),'%');
                app.progress_FG.Position(3) = max(10,max(0,floor(app.progress*app.progress_BG.Position(3))-6));
            else
                stop(app.appTimer);
                delete(app.appTimer);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Start');
            % start timer

            app.timeRemaining = 3600*app.RuntimehrEditField.Value;
            app.timeElapsed = 0;

            disp(app.timeRemaining);

            app.appTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ...                 % seconds
                'TimerFcn', @(~,~) countdownCallback(app));
        
            start(app.appTimer);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            disp('Press Start');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.device = visadev("USB0::0x0AAD::0x01A0::102106::INSTR");
                configureTerminator(app.device,"LF");
                app.device.Timeout = 10;
        
                idn = strtrim(writeread(app.device,"*IDN?"));
                disp(idn);
        
                app.ConnectionStatusLamp.Color = [0 1 0];
            catch ME
                disp(ME.message);
                app.ConnectionStatusLamp.Color = [1 0 0];
            end 
            

        end

        % Button pushed function: ReaddataButton
        function ReaddataButtonPushed(app, event)
            app.SavingDataLamp.Color ='g'; 
            % Make timeout large for big ASCII transfers
            app.device.Timeout = 60;

        
            % (Optional but helpful) ensure ASCII data
            writeline(app.device,"FORM:DATA ASC");
            timestamp = datetime("now");
            timestampStr = string(timestamp);
            % -------------------------------
            % Read Bluefors temperatures
            % -------------------------------
            try
                baseURL = "http://127.0.0.1:49099/values/";
            
                url_c1 = baseURL + "mapper.heater_mappings_bftc.device.c1/temperature";
                url_c2 = baseURL + "mapper.heater_mappings_bftc.device.c2/temperature";
                url_c5 = baseURL + "mapper.heater_mappings_bftc.device.c5/temperature";
                url_c6 = baseURL + "mapper.heater_mappings_bftc.device.c6/temperature";
            
                r1 = webread(url_c1);
                r2 = webread(url_c2);
                r5 = webread(url_c5);
                r6 = webread(url_c6);
            
                T_c1 = r1.data.mapper_heater_mappings_bftc_device_c1_temperature.content.latest_value.value
                % return
                T_c2 = r2.data.mapper_heater_mappings_bftc_device_c2_temperature.content.latest_value.value;
                T_c5 = r5.data.mapper_heater_mappings_bftc_device_c5_temperature.content.latest_value.value;
                T_c6 = r6.data.mapper_heater_mappings_bftc_device_c6_temperature.content.latest_value.value;
           
            catch ME
                disp("Bluefors read error:");
                disp(ME.message);
            
                T_c1 = NaN;
                T_c2 = NaN;
                T_c5 = NaN;
                T_c6 = NaN;
            end
            % Frequency axis (stimulus)
            ftxt = strtrim(writeread(app.device,"CALC2:DATA:STIM?"));   % stimulus values :contentReference[oaicite:2]{index=2}
            fHz = sscanf(strrep(ftxt,',',' '), '%f');
        
            % Complex trace data (Re/Im interleaved)
            dtxt = strtrim(writeread(app.device,"CALC2:DATA? SDAT"));   % Re/Im pairs :contentReference[oaicite:3]{index=3}
            y = sscanf(strrep(dtxt,',',' '), '%f');
        
            re = y(1:2:end);
            im = y(2:2:end);
            s  = complex(re, im);
        
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            fHz = fHz(1:n);
            s   = s(1:n);
            disp(fHz);
            % disp(s);
            disp("done read");
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            % return
            fHz = fHz(1:n);
            s   = s(1:n);
            
            
            % Convert to useful quantities
            mag_dB = 20*log10(abs(s));
            phase_deg = angle(s)*180/pi;
            real_part = real(s);
            imag_part = imag(s);
            
            % Create table

            % T = table( ...
            %    repmat(timestamp, n, 1), ...
            %    fHz, real_part, imag_part, mag_dB, phase_deg, ...
            %    'VariableNames', {'Timestamp','Frequency_Hz','Real','Imag','Magnitude_dB','Phase_deg'});
            T = table( ...
                repmat(timestamp, n, 1), ...
                fHz, ...
                real_part, ...
                imag_part, ...
                mag_dB, ...
                phase_deg, ...
                repmat(T_c1, n, 1), ...
                repmat(T_c2, n, 1), ...
                repmat(T_c5, n, 1), ...
                repmat(T_c6, n, 1), ...
                'VariableNames', { ...
                'Timestamp', ...
                'Frequency_Hz', ...
                'Real', ...
                'Imag', ...
                'Magnitude_dB', ...
                'Phase_deg', ...
                'Temp_C1_K', ...
                'Temp_C2_K', ...
                'Temp_C5_K', ...
                'Temp_C6_K'});
            % Create filename with timestamp
            filename = "../VNA_data/ZNB_trace_" + datestr(now,'yyyymmdd_HHMMSS') + ".csv";
            
            % Save file
            writetable(T, filename);
            
            disp("Saved file:");
            disp(filename);

            
            % Open file manually
            fid = fopen(filename, 'w');
            disp([T_c1 T_c2 T_c5 T_c6])
            % return
            % Write metadata header
            fprintf(fid, 'Measurement Timestamp: %s\n', string(timestamp));
            fprintf(fid, 'Bluefors Temperatures (K):\n');
    

            fprintf(fid, 'C1: %s\n', T_c1);
            fprintf(fid, 'C2: %s\n', T_c2);
            fprintf(fid, 'C5: %s\n', T_c5);
            fprintf(fid, 'C6: %s\n', T_c6);
            fprintf(fid, '\n')
            % Write column header
            fprintf(fid, 'Frequency_Hz,Real,Imag,Magnitude_dB,Phase_deg\n');
            
            % Write numeric data
            for k = 1:n
                fprintf(fid, '%.12e,%.12e,%.12e,%.6f,%.6f\n', ...
                    fHz(k), real_part(k), imag_part(k), mag_dB(k), phase_deg(k));
            end
            
            fclose(fid);
            
            disp("Saved file with header:");
            disp(filename);
            app.SavingDataLamp.Color = 'r';

            % Plot in app axes
            app.sParamLine = plot(app.sParamAxes,fHz,mag_dB);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.SweepStartMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStartMHzEditFieldLabel.Text = 'Sweep Start (MHz)';
            app.SweepStartMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SweepStopMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStopMHzEditFieldLabel.Text = 'Sweep Stop (MHz)';
            app.SweepStopMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RBWHzEditFieldLabel = uilabel(app.LeftPanel);
            app.RBWHzEditFieldLabel.Text = 'RBW (Hz)';
            app.RBWHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointsEditFieldLabel = uilabel(app.LeftPanel);
            app.PointsEditFieldLabel.Text = '# Points ';
            app.PointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MessageTextAreaLabel = uilabel(app.LeftPanel);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.LeftPanel);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ReaddataButton = uibutton(app.LeftPanel, 'push');
            app.ReaddataButton.ButtonPushedFcn = createCallbackFcn(app, @ReaddataButtonPushed, true);
            app.ReaddataButton.Text = 'Read data';
            app.RuntimehrEditFieldLabel = uilabel(app.LeftPanel);
            app.RuntimehrEditFieldLabel.Text = 'Runtime (hr)';
            app.RuntimehrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RuntimehrEditField.Value = 0.005;
            app.RightPanel = uipanel(app.UIFigure);
            app.dataCollectAxes = uiaxes(app.RightPanel);
            title(app.dataCollectAxes, 'Total Data Collected')
            xlabel(app.dataCollectAxes, 'Time (hr)')
            ylabel(app.dataCollectAxes, 'Data (MB)')
            zlabel(app.dataCollectAxes, 'Z')
            app.sParamAxes = uiaxes(app.RightPanel);
            title(app.sParamAxes, 'S21')
            xlabel(app.sParamAxes, 'Frequency (MHz)')
            ylabel(app.sParamAxes, 'dB(|S21|)')
            zlabel(app.sParamAxes, 'Z')
            app.StateEditFieldLabel = uilabel(app.RightPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.RightPanel, 'text');
            app.ProgressLabel = uilabel(app.RightPanel);
            app.ProgressLabel.Text = 'Progress:';
            app.percentLabel = uilabel(app.RightPanel);
            app.percentLabel.Text = '0%';
            app.progress_BG = uilabel(app.RightPanel);
            app.progress_BG.Text = '';
            app.progress_FG = uilabel(app.RightPanel);
            app.progress_FG.Text = '';
            app.SweepingLampLabel = uilabel(app.RightPanel);
            app.SweepingLampLabel.Text = 'Sweeping';
            app.SweepingLamp = uilamp(app.RightPanel);
            app.SavingDataLampLabel = uilabel(app.RightPanel);
            app.SavingDataLampLabel.Text = 'Saving Data';
            app.SavingDataLamp = uilamp(app.RightPanel);
            app.CurrentFilenameEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentFilenameEditFieldLabel.Text = 'Current Filename';
            app.CurrentFilenameEditField = uieditfield(app.RightPanel, 'text');
            app.CollectedFilesListBoxLabel = uilabel(app.RightPanel);
            app.CollectedFilesListBoxLabel.Text = 'Collected Files';
            app.CollectedFilesListBox = uilistbox(app.RightPanel);
            app.CollectedFilesListBox.Items = {''};
            app.CollectedFilesListBox.Value = '';
            app.ElapsedEditFieldLabel = uilabel(app.RightPanel);
            app.ElapsedEditFieldLabel.Text = 'Elapsed';
            app.ElapsedEditField = uieditfield(app.RightPanel, 'text');
            app.RemainingEditFieldLabel = uilabel(app.RightPanel);
            app.RemainingEditFieldLabel.Text = 'Remaining';
            app.RemainingEditField = uieditfield(app.RightPanel, 'text');
            app.ConnectionStatusLampLabel = uilabel(app.RightPanel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VNA_control_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end